classdef app_eng < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PredicciondelcomportamientoacsticodeAltavocesUIFigure  matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        ExportCurrentChartMenu        matlab.ui.container.Menu
        ExportIRtxtMenu               matlab.ui.container.Menu
        GridLayout                    matlab.ui.container.GridLayout
        PanelAcciones                 matlab.ui.container.Panel
        GridLayout5                   matlab.ui.container.GridLayout
        SubwooferSpeakersButton       matlab.ui.control.Button
        SimulateRepresentButton       matlab.ui.control.Button
        LineArraySpeakersButton       matlab.ui.control.Button
        PanelProm                     matlab.ui.container.Panel
        GridLayout4                   matlab.ui.container.GridLayout
        AveragedDropDown              matlab.ui.control.DropDown
        AveragedDropDownLabel         matlab.ui.control.Label
        PanelM_F                      matlab.ui.container.Panel
        imagen_logo                   matlab.ui.control.Image
        PanelGraf                     matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        ResolucionY                   matlab.ui.control.NumericEditField
        ResolucinXLabel_2             matlab.ui.control.Label
        ResolucionX                   matlab.ui.control.NumericEditField
        ResolutionXLabel              matlab.ui.control.Label
        OffsetY                       matlab.ui.control.NumericEditField
        OffsetYEditField_2Label       matlab.ui.control.Label
        EjeY                          matlab.ui.control.NumericEditField
        EjeYEditField_2Label          matlab.ui.control.Label
        OffsetX                       matlab.ui.control.NumericEditField
        XoffsetLabel                  matlab.ui.control.Label
        EjeX                          matlab.ui.control.NumericEditField
        EjeXEditFieldLabel            matlab.ui.control.Label
        PanelBotones                  matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        dBrangeSlider                 matlab.ui.control.Slider
        dBrangeSliderLabel            matlab.ui.control.Label
        Tipo_alt6                     matlab.ui.control.DropDown
        Tipo_alt5                     matlab.ui.control.DropDown
        Tipo_alt4                     matlab.ui.control.DropDown
        Tipo_alt3                     matlab.ui.control.DropDown
        Tipo_alt2                     matlab.ui.control.DropDown
        Tipo_alt1                     matlab.ui.control.DropDown
        tipo_alt                      matlab.ui.control.Label
        Altavoz6EditFieldLabel        matlab.ui.control.Label
        Altavoz6_grados               matlab.ui.control.NumericEditField
        Altavoz6Y                     matlab.ui.control.NumericEditField
        Altavoz6X                     matlab.ui.control.NumericEditField
        FrequencyHzLabel              matlab.ui.control.Label
        Freq                          matlab.ui.control.NumericEditField
        GrficaarepresentarDropDownLabel  matlab.ui.control.Label
        GraficaDropDown               matlab.ui.control.DropDown
        PresinLabel_3                 matlab.ui.control.Label
        PresinLabel_2                 matlab.ui.control.Label
        PresinLabel                   matlab.ui.control.Label
        Humedad                       matlab.ui.control.NumericEditField
        Temp                          matlab.ui.control.NumericEditField
        Presion                       matlab.ui.control.NumericEditField
        WeatherConditionsLabel        matlab.ui.control.Label
        yLabel                        matlab.ui.control.Label
        xLabel                        matlab.ui.control.Label
        MicroY                        matlab.ui.control.NumericEditField
        MicroX                        matlab.ui.control.NumericEditField
        MicrophonePositionLabel       matlab.ui.control.Label
        Altavoz5EditFieldLabel        matlab.ui.control.Label
        Altavoz4EditFieldLabel        matlab.ui.control.Label
        Altavoz3EditFieldLabel        matlab.ui.control.Label
        Altavoz2EditFieldLabel        matlab.ui.control.Label
        Altavoz5_grados               matlab.ui.control.NumericEditField
        Altavoz4_grados               matlab.ui.control.NumericEditField
        Altavoz3_grados               matlab.ui.control.NumericEditField
        Altavoz2_grados               matlab.ui.control.NumericEditField
        Altavoz5Y                     matlab.ui.control.NumericEditField
        Altavoz4Y                     matlab.ui.control.NumericEditField
        Altavoz3Y                     matlab.ui.control.NumericEditField
        Altavoz2Y                     matlab.ui.control.NumericEditField
        Altavoz5X                     matlab.ui.control.NumericEditField
        Altavoz4X                     matlab.ui.control.NumericEditField
        Altavoz3X                     matlab.ui.control.NumericEditField
        Altavoz2X                     matlab.ui.control.NumericEditField
        grados                        matlab.ui.control.Label
        YLabel                        matlab.ui.control.Label
        XLabel                        matlab.ui.control.Label
        Altavoz1EditFieldLabel        matlab.ui.control.Label
        Altavoz1_grados               matlab.ui.control.NumericEditField
        Altavoz1Y                     matlab.ui.control.NumericEditField
        Altavoz1X                     matlab.ui.control.NumericEditField
        NumberofspeakersSpinner       matlab.ui.control.Spinner
        NumberofspeakersSpinnerLabel  matlab.ui.control.Label
        UIEje                         matlab.ui.control.UIAxes
    end

    
     properties (Access = private)
        % We define the interface’s private properties to make it easier to call them from within the application’s functions
        SpeakersBox;
        Y;
        F;
        ang_resp1m;
        Y_array;
        F_array;
        ang_resp1m_array;
        Y_sub;
        F_sub;
        ang_resp1m_sub;
        n_speakers;
        n_sub;
        n_array;
        r_microphone;
        f;
        R_speaker;
        R_speaker_array;
        R_speaker_sub;
        fs;
        res;
        graph;
        pressure;
        temperature;
        relative_humidity;
        ang_offset;
        ang_array;
        ang_sub;
        mesh_x;
        mesh_y
        H_eq;
        N;
        avg;
        angles;
        FFT_ang_array;
        FFT_ang_sub;
        IR_array;
        IR_sub;
        targetAngles;
        audiopath_array;
        audiopath_sub;
        route_array;
        route_sub;
        range_db;
        c_amb;
        apha_atm_cond;
        microphone_pointer;
    end
    
    methods (Access = private)
        
        % SIGNAL PROCESSING ------------------------------------------
        function Htotal=ec_onda(app,resp1mfft,n_speakers,fs,r,R_speaker,pressure,temperature,relative_humidity)
            L=32768; % Magnitude of the Fourier transform
            ff=(fs/L)*(0:L-1); % The frequency axis is obtained using the formula for spectral resolution
            ff((L/2)+1:end) = ff((L/2)+1:end) - fs; % To comply with Nyquist’s theorem, the sampling frequency is subtracted from the second half of the vector in order to remap the high frequencies to negative frequencies.
            f_vector=ff;
            app.c_amb=343.2 * sqrt(temperature / 293.15);
            landa=app.c_amb./f_vector;
            k = (2*pi)./landa; % The wave number is calculated
            
            alfa_amb=condiciones_amb(app,pressure,temperature,relative_humidity, f_vector); % The attenuation coefficient due to environmental conditions, expressed in dB/m, is determined for all frequencies
        
            H = zeros(n_speakers, L, 'like', complex(0)); % The variable that will store the result is initialised to 0, with the number of speakers as the row size and the size of the transform as the number of columns.
            for i_alt= 1:1:n_speakers
                dx = r(1) - R_speaker(i_alt, 1); % The distance between the source and the measurement point on the X-axis is calculated
                dy = r(2) - R_speaker(i_alt, 2); % The distance between the source and the measurement point on the Y-axis is calculated
                dist = sqrt(dx^2 + dy^2); % The distance between the loudspeaker and the microphone is calculated using Pythagoras’ theorem
                
                if dist < 0.01
                    dist = 0.01; % If the distance is less than 0.01, it is set to 0.01 to avoid problems with uncertainty calculations.
                end
                
                ec = exp(-(1i * k * (dist - 1))) ./ dist; % The spherical wave equation is derived
                H(i_alt, :) = resp1mfft(i_alt, :) .* ec .* exp(-alfa_amb * dist / 8.686); % The impulse response in the frequency domain is multiplied by the spherical wave equation and by the attenuation coefficient of 8.686 to convert the coefficient to Neperios/m, and is stored in the variable H
        
            end
            Htot=sum(H,1); % The results of the equation for each loudspeaker, stored by row, are added together to obtain the total result at the measurement point.
            Htotal=ifft(Htot,'symmetric'); % The data is transformed into the time domain, ensuring that the imaginary part is 0.
        end

        function H_alineada=sincronizacion(app,H)
            [~, idx_pico] = max(abs(H)); % The maximum level is sought, which will correspond to the arrival of the wavefront from the loudspeaker closest to the microphone, in order to set this as the starting point.
            H_alineada = circshift(H, -idx_pico + 1); % The frequency response H is shifted in a circular manner by an amount of -idx_pico + 1 (where negative values indicate starting from the end).
            
        end
    
        function [Y,F]=fft_octavas(app,Htotal,fs)
            fftSizes = [32768, 16384, 8192, 4096, 2048, 1024, 512, 256]; % Se definen los tamaños de las transformadas de Fourier
            
            % The frequency axis is calculated using the spectral resolution formula in accordance with Nyquist’s theorem
            f1=(fs/fftSizes(1))*(0:fftSizes(1)/2);
            f2=(fs/fftSizes(2))*(0:fftSizes(2)/2);
            f3=(fs/fftSizes(3))*(0:fftSizes(3)/2);
            f4=(fs/fftSizes(4))*(0:fftSizes(4)/2);
            f5=(fs/fftSizes(5))*(0:fftSizes(5)/2);
            f6=(fs/fftSizes(6))*(0:fftSizes(6)/2);
            f7=(fs/fftSizes(7))*(0:fftSizes(7)/2);
            f8=(fs/fftSizes(8))*(0:fftSizes(8)/2);
            
            % The transform of the same signal is carried out using different numbers of transform points
            fft1=fft(Htotal, fftSizes(1));
            fft2=fft(Htotal, fftSizes(2));
            fft3=fft(Htotal, fftSizes(3));
            fft4=fft(Htotal, fftSizes(4));
            fft5=fft(Htotal, fftSizes(5));
            fft6=fft(Htotal, fftSizes(6));
            fft7=fft(Htotal, fftSizes(7));
            fft8=fft(Htotal, fftSizes(8));
            
            % The amplitude data corresponding to that octave is selected
            Y1 = fft1(f1 >= 1.46 & f1 <= 187.5);
            Y2 = fft2(f2 >= 187.5 & f2 < 375);
            Y3 = fft3(f3 >= 375 & f3 < 750);
            Y4 = fft4(f4 >= 750 & f4 < 1500);
            Y5 = fft5(f5 >= 1500 & f5 < 3000);
            Y6 = fft6(f6 >= 3000 & f6 < 6000);
            Y7 = fft7(f7 >= 6000 & f7 < 12000);
            Y8 = fft8(f8 >= 12000 & f8 < 24000);
            
            Y=[Y1,Y2,Y3,Y4,Y5,Y6,Y7,Y8]; % All the data is combined into a single variable
            
            % The data for the frequency axis corresponding to that octave is selected
            xf1=f1(f1 >= 1.46 & f1 <= 187.5);
            xf2=f2(f2 >= 187.5 & f2 < 375);
            xf3=f3(f3 >= 375 & f3 < 750);
            xf4=f4(f4 >= 750 & f4 < 1500);
            xf5=f5(f5 >= 1500 & f5 < 3000);
            xf6=f6(f6 >= 3000 & f6 < 6000);
            xf7=f7(f7 >= 6000 & f7 < 12000);
            xf8=f8(f8 >= 12000 & f8 < 24000);
            
            F=[xf1,xf2,xf3,xf4,xf5,xf6,xf7,xf8]; % All the data is combined into a single variable
        end
        
        function Hinterp = interpolateIRAngle(app,IR_fft, angles, targetAngles, method)
            if nargin < 4
                method = 'pchip'; % If the user does not specify an interpolation method, “pchip” will be used by default
            end
        
            [Nang, Nsamples] = size(IR_fft); % The number of angles and samples is obtained
        
            if length(angles) ~= Nang
                error('The number of angles must match the number of IR rows.');
            end
        
            % To interpolate circularly, a circular extension is carried out
            dAng1 = angles(2) - angles(1); % The angular step is calculated
            anglesExt = [angles(1)-dAng1, angles, angles(end)+dAng1]; % A value less than 0 is added before 0 and a value greater than 360 is added after 360 to ensure a smooth transition between the last and first angles
            Hext = [IR_fft(end,:); IR_fft; IR_fft(1,:)]; % The last row is copied to the beginning and the first row to the end of the response to the prompt to ensure continuity
        
            Hinterp = zeros(length(targetAngles), Nsamples);  % We initialise the variable to store the interpolated array
        
            for k = 1:Nsamples
                % We separate the real and imaginary parts into columns so that they can be interpolated separately, thereby avoiding phase errors
                re = abs(Hext(:,k));
                im = imag(Hext(:,k));
                
                % We interpolate the real and imaginary parts column-wise for the angles using the `interp1` function for the desired angles in `targetAngles`
                re_i = interp1(anglesExt, re, targetAngles, method);
                im_i = interp1(anglesExt, im, targetAngles, method);
        
                Hinterp(:,k) = re_i + 1j*im_i; % The complex number is reconstructed and stored
            end
        end
        
        function H_mallado=calc_h_malla(app,IR05,targetAngles,n_speakers,R_speaker,f,fs,ang_offset,pressure,temperature,relative_humidity)
            app.apha_atm_cond=condiciones_amb(app,pressure, temperature,relative_humidity, f);  % Calculate airborne attenuation in accordance with ISO 9613-1 in dB/m
            
            L=32768; % Magnitude of the Fourier transform
            ff=(fs/L)*(0:L-1); % The frequency axis is obtained using the formula for spectral resolution
            ff((L/2)+1:end) = ff((L/2)+1:end) - fs; % To comply with Nyquist’s theorem, the sampling frequency is subtracted from the second half of the vector in order to remap the high frequencies to negative frequencies.
            [~,pos_frec]=f_cercana(app,f,ff); % The system searches within the frequency range for the frequency closest to the one entered by the user
            IR05_f=IR05(:, pos_frec); % The column containing the values for the desired frequency is extracted from the interpolated response to reduce the computational load

            app.c_amb=343.2 * sqrt(temperature / 293.15); % The speed of sound is calculated using the ambient temperature and the reference temperature
            H_mallado = zeros(size(app.mesh_x,1),size(app.mesh_y,2), 'like', complex(single(0))); % The mesh matrix is stored in memory with the variable filled with complex zeros
            
            for i_alt=1:1:n_speakers
                dx = app.mesh_x - R_speaker(i_alt, 1); % The distance between the source and the measurement point on the X-axis is calculated
                dy = app.mesh_y - R_speaker(i_alt, 2); % The distance between the source and the measurement point on the Y-axis is calculated
                dist=sqrt(dx.^2+dy.^2); % The distance between the loudspeaker and the microphone is calculated using Pythagoras’ theorem
                dist(dist < 0.01) = 0.01; % If the distance is less than 0.01, it is set to 0.01 to avoid problems with uncertainty calculations
                
                ang=atan2d(dy,dx)-ang_offset(i_alt); % The angle of each point in the mesh relative to the loudspeaker is calculated, and the loudspeaker’s own tilt angle is subtracted
                ang_positivo = mod(ang, 360); % Ensure that the angle is between 0 and 360
                pos = round(ang_positivo / 0.5)+1; % The angle value is converted to the equivalent position in the matrix of interpolated values with a resolution of 0.5, and 1 is added to it because indexing in MATLAB starts at 1
                pos(pos > length(targetAngles))=1; % A limit is set in case the number of angles is exceeded; the final position of the matrix is set

                fase = exp(-1j * 2 * pi * f * (((dist - 1) / app.c_amb)));  % The spherical wave equation is derived
                H_mallado = H_mallado + (IR05_f(pos)./dist).*fase .*exp(-app.apha_atm_cond*dist/8.686); % The impulse response in the frequency domain is multiplied by the spherical wave equation and by the attenuation coefficient of 8.686 to convert the coefficient to Neperios/m, and is stored in the variable H

            end
        end
        
        function [Y, F,ang_resp1m]=calc_Ir_microphone(app,r_microphone,R_speaker,ang_offset,FFT_ang,n_speakers,angles,fs,pressure,temperature,relative_humidity)

            ang_resp1m=zeros(n_speakers, 32768, 'like', complex(0)); % The mesh matrix is stored in memory with the variable filled with complex zeros
            for i_alt=1:1:size(R_speaker,1)
                ang=calc_angulo(app,r_microphone,R_speaker(i_alt,:))-ang_offset(i_alt);  % The angle of each point in the mesh relative to the loudspeaker is calculated, and the loudspeaker’s own tilt angle is subtracted
                ang_positivo = mod(ang, 360); % Ensure that the angle is between 0 and 360

                [~,pos]=min(abs(angles-ang_positivo)); % The vector representing all angles is sought; the angle closest to that obtained from the calculation
                ang_resp1m(i_alt,:)=FFT_ang(pos,:); % The measured position response is stored
            end
            app.H_eq=ec_onda(app,ang_resp1m,n_speakers,fs,r_microphone,R_speaker,pressure,temperature,relative_humidity); % The wave equation applies from the loudspeakers to the microphone
            H_autoc=sincronizacion(app,app.H_eq); % The various responses are synchronised
            [Y, F] = fft_octavas(app,H_autoc, fs); % The 64FPPO method is applied to the synchronised signal
        
        end
        
        function apha_atm_cond=condiciones_amb(app,pressure, temperature,relative_humidity, frequency)
            %ISO 9613 1 1993
            soundspeed = 343.2; % Speed of sound.
            reference_temperature= 293.15; % Reference temperature.
            reference_pressure = 101.325; % International Standard Atmosphere in kilopascal.    
            triple_temperature = 273.16;  % Triple point isotherm temperature.
        
            c_amb=soundspeed * sqrt(temperature / reference_temperature); % The actual speed of sound is calculated
            saturation_pressure=reference_pressure * 10.0^(-6.8346 * (triple_temperature / temperature)^(1.261) + 4.6151); % The saturation vapour pressure is calculated
            h=relative_humidity * saturation_pressure / pressure; % The molar concentration of water vapour is calculated
            relaxation_frequency_oxygen=pressure / reference_pressure * (24.0 + 4.04 * 10.0^4.0 * h * (0.02 + h) / (0.391 + h));% The relaxation frequency of oxygen is calculated
            relaxation_frequency_nitrogen=pressure / reference_pressure * (temperature / reference_temperature)^(-0.5) * (9.0 + 280.0 * h * exp(-4.170 * ((temperature / reference_temperature)^(-1.0 / 3.0) - 1.0))); % The relaxation frequency of nitrogen is calculated
            % The atmospheric attenuation coefficient is calculated taking into account whether `frequency` is a vector or a scalar
            if(size(frequency,2)>1)
                apha_atm_cond = 8.686 .* frequency.^(2.0) .* ((1.84 * 10.0^(-11.0) * (reference_pressure / pressure) * (temperature / reference_temperature)^(0.5)) + (temperature / reference_temperature)^(-2.5) * (0.01275 * exp(-2239.1 / temperature) .* (relaxation_frequency_oxygen + (frequency.^2.0 ./ relaxation_frequency_oxygen)).^(-1.0) + 0.1068 * exp(-3352.0 / temperature) .* (relaxation_frequency_nitrogen + (frequency.^2.0 ./ relaxation_frequency_nitrogen)).^(-1.0)));
            else
                apha_atm_cond = 8.686 * frequency^(2.0) *((1.84 * 10.0^(-11.0) * (reference_pressure / pressure) * (temperature / reference_temperature)^(0.5)) + (temperature / reference_temperature)^(-2.5) * (0.01275 * exp(-2239.1 / temperature) * (relaxation_frequency_oxygen + (frequency^2.0 / relaxation_frequency_oxygen))^ (-1.0) + 0.1068 * exp(-3352.0 / temperature) * (relaxation_frequency_nitrogen + (frequency^2.0 / relaxation_frequency_nitrogen))^(-1.0)));
            end
            
        end
            
        % AUXILIARS -----------------------------------------------------
        function [frec,pos]=f_cercana(app,f0,f)
            %Given the frequency vector f and the frequency f₀, we seek the position in the vector f and the frequency f that is closest to f₀
            [~,pos]=min(abs(f0-f));
            frec=f(pos);
        end
        
        function ang=calc_angulo(app,pos_micro,pos_alt)
            dy=pos_micro(2)-pos_alt(2); % The distance between the source and the measurement point on the Y-axis is calculated
            dx=pos_micro(1)-pos_alt(1); % The distance between the source and the measurement point on the X-axis is calculated
            ang=atan2d(dy,dx); % Calculate the angle in degrees between the loudspeaker and the microphone, ensuring that it lies within the correct quadrant (0 to 360)
        end
        
        function [FFT_ang,angles,paso]=matriz_fft_angulo(app,ruta_carpeta)
            archivos = dir(fullfile(ruta_carpeta, '*.wav')); % The folder in the working directory called ‘audios_angulos’ is analysed, and a struct-type variable is created to store the characteristics of the .wav files in alphabetical order (0, 10, 100, 15, 20...))
            n_archivos=length(archivos); % The number of files in this folder is retrieved
            
            % The variables to be used to store the results are initialised
            FFT_ang=zeros(n_archivos,32768);
            angles=zeros(1,n_archivos);

            % An iterative loop will be used to iterate through the number of files in the folder
            for i_arc=1:1:n_archivos
                ruta_arc=archivos(i_arc).folder+"/"+archivos(i_arc).name; % The full path of the audio file to be stored is created
                [audio,~]=audioread(ruta_arc); % The audio file is played
        
                FFT_ang(i_arc,:)=fft(audio,32768);  % The Fourier transform is performed to transform the signal into the frequency domain with a constant number of samples (32,768)
        
                ang=regexp(archivos(i_arc).name, '\d+(_\d+)?', 'match');  % Numeric data is extracted from the file name; \d+ matches one or more digits. (_\d+)? optionally matches an underscore followed by digits to match decimals. From “12_5.wav”, the pattern extracts “12_5”.
                texto_angulo = ang{end};  % We extract the last digit from the .wav file name
                angulo_corregido = strrep(texto_angulo, '_', '.'); % Replaces the underscore in the name with a decimal point to facilitate the conversion of a text string to a double-precision number
                angles(i_arc) = str2double(angulo_corregido); % Convert the text to double
        
            end
            
            [angles, indices_orden] = sort(angles); % The files are sorted in ascending numerical order, not alphabetical order
            FFT_ang = FFT_ang(indices_orden, :); % The rows of FFT_ang are reordered using the indices obtained from the ascending sort, so that row 1 corresponds to angle 0, and so on.
            
             % As only the audio files from 0° to 180° are available, spatial symmetry of the loudspeaker is assumed and the response is duplicated at the symmetrical angle
            for i=n_archivos-1:-1:2
                angles(end+1)=360-angles(i); % We calculate the symmetric angle
                FFT_ang(end+1,:)=FFT_ang(i,:); % The FFT of the symmetric angle response is appended to the end of the array
            end  
        
            pasos=diff(angles); % The step between the angles is calculated to obtain the angular resolution.
            paso=pasos(1); % The spatial resolution is obtained
        end

        % REPRESENTATION --------------------------------------------------
        function mapa(app,r,graph)
            %graph=[offset;ejes];
            hold (app.UIEje,'on');
            long=50; % Length of the directivity line
            if app.n_array>0
                for i = 1:1:app.n_array
                    x = app.R_speaker_array(i, 1); % The measurement point on the X-axis of the ‘Line array’ loudspeaker is obtained
                    y = app.R_speaker_array(i, 2); % The measurement point on the Y-axis of the ‘line array’ loudspeaker is obtained
                    ang_rad=deg2rad(app.ang_array(i)); % Convert the speaker’s angle of inclination from decimal degrees to radians
                    
                    lin_x=x+long*cos(ang_rad); % The position vector along the x-axis of the line of maximum directivity is calculated
                    lin_y=y+long*sin(ang_rad); % The position vector of the y-axis and the line of maximum directivity is calculated
                    
                    plot(app.UIEje,[x,lin_x],[y,lin_y],'k-','LineStyle','--','LineWidth', 1); % The straight line is shown
                    plot(app.UIEje,app.R_speaker_array(i,1),app.R_speaker_array(i,2),'square', 'MarkerSize', 11, 'MarkerFaceColor', [0.9 0.9 0.9],'MarkerEdgeColor', 'r'); % The ‘line array’ speakers are shown on the map
                    
                end
            end

            if app.n_sub>0
                for i = 1:1:app.n_sub
                    x = app.R_speaker_sub(i, 1); % The measurement point on the X-axis of the ‘Subwoofer’ loudspeaker is obtained
                    y = app.R_speaker_sub(i, 2); % The measurement point on the Y-axis of the ‘Subwoofer’ loudspeaker is obtained
                    ang_rad=deg2rad(app.ang_sub(i)); % Convert the speaker’s angle of inclination from decimal degrees to radians
                    
                    lin_x=x+long*cos(ang_rad); % The position vector along the x-axis of the line of maximum directivity is calculated
                    lin_y=y+long*sin(ang_rad); % The position vector of the y-axis and the line of maximum directivity is calculated
                    
                    plot(app.UIEje,[x,lin_x],[y,lin_y],'k-','LineStyle','--','LineWidth', 1); % The straight line is shown
                    plot(app.UIEje,app.R_speaker_sub(i,1),app.R_speaker_sub(i,2),'square', 'MarkerSize', 14, 'MarkerFaceColor', [0.5 0.5 0.5],'MarkerEdgeColor', 'g'); % ‘Subwoofer’ speakers are shown on the map
                    
                end
            end
            

            plot(app.UIEje,r(1),r(2),'bo', 'MarkerSize', 12, 'MarkerFaceColor', [0.7 0.7 0.7],'MarkerEdgeColor', 'b'); % The microphone is represented as a circle (“o”) with a blue border (“b”), where r(1) is the X-coordinate and r(2) is the Y-coordinate
            
            axis(app.UIEje,[graph(1,1) graph(1,1)+graph(2,1)  graph(1,2) graph(1,2)+graph(2,2)]) % Ajusta los límites de la gráfica basándose en la matriz graph
            
            % Labels and the aesthetics of representation
            xlabel(app.UIEje,'X-Axis (m)'); 
            ylabel(app.UIEje,'Y-Axis (m)');
            title(app.UIEje,'Configuration Diagram');
            grid (app.UIEje,'minor'); % Add a fine grid to see the distances more clearly
        
            hold(app.UIEje,'off'); % Release the graph
        
        end
        
        function [mesh_x,mesh_y]=mallado(app,res,graph)
            % Extract the axis resolution from the ‘graph’ variable (graph=[offset;axes])
            Ax=res(1);
            Ay=res(2);

            % Create a horizontal and vertical vector extending from the start (X offset and Y offset) to the end, with increments equal to the resolution size (Ax or Ay).
            Vfila=graph(1,1):Ax:graph(1,1)+graph(2,1); % It moves horizontally
            Vcolumna=graph(1,2):Ay:graph(1,2)+graph(2,2); % It moves vertically
            [mesh_x,mesh_y]=meshgrid(Vfila,Vcolumna); % Surf requires the data to be stored in separate matrices; for this reason, the `meshgrid` function is used, which returns 2D grid coordinates based on the coordinates contained in the vectors `x` and `y`. `X` is a matrix in which each row is a copy of `x`, whilst `Y` is a matrix in which each column is a copy of `y`.
            
        end
        
        function representacionM_F(app,Y,F)
            % Magnitude
            t = tiledlayout(app.PanelM_F, 2, 1); % Divides the display window into 2 rows and 1 column, and position 1 (top row) is used
            ax1 = nexttile(t); % The first division is obtained to represent the magnitude
            
            semilogx(ax1,F, 20*log10(abs(Y))); % It plots the magnitude of the Y signal on a logarithmic scale, converting the complex magnitude to dB
            % Labels and the aesthetics of representation
            xlabel(ax1,'Frequency (Hz)'); 
            ylabel(ax1,'Magnitude (dB)');
            title(ax1,'Magnitude by Octaves');
            xlim(ax1,[20 20000]) % It restricts the view to the human audible spectrum
            
            % Phase
            ax2 = nexttile(t); % The following division is obtained to represent the phase

            semilogx(ax2,F, 180/pi*angle(Y)); % Extrae el Angle en radianes (angle), lo convierte a grados (180/pi) y lo representa en uan escala logarítmica
            % Labels and the aesthetics of representation
            xlabel(ax2,'Frequency (Hz)');
            ylabel(ax2,'Phase');
            title(ax2,'Phase');
            axis(ax2,[20 20000 -180 180]); % It restricts the view to the human audible spectrum y como la fase siempre oscila entre -180º y 180º, se limita el eje Y entre -180 y 180
        end
        
        function SPL_R_x(app,r,f,fs)
            landa=app.c_amb/f; % The wavelength is calculated
            k = 2*pi/landa; % The wave number is calculated

            x_coord = r(1); % The x-coordinate of the microphone’s position is extracted
            if app.n_array > 0
                x_coord = [x_coord, app.R_speaker_array(:, 2)']; % The X-coordinates of all line array speakers are added to the list to calculate the boundaries of the display dynamically
            end
            if app.n_sub > 0
                x_coord = [x_coord, app.R_speaker_sub(:, 2)']; % The X-coordinates of all subwoofers are added to the list to calculate the display boundaries dynamically
            end
            
            % It finds the furthest points and gives them a visual margin of 5 cm
            limite_inf = min(x_coord) - 0.05;
            limite_sup = max(x_coord) + 0.05;
            
            % If the measurement point and the loudspeakers are in line with one another, the minimum values are set to -1 and +1 so that the graph can be plotted
            if limite_inf == limite_sup
                limite_inf = -1;
                limite_sup = 1;
            end

            distancias = limite_inf:0.05:limite_sup; % The spatial vector is created with a 5 cm spacing between the two boundaries
        
            L = 32768; % Magnitude of the Fourier transform
            ff = (fs/L)*(0:L-1); % The frequency axis is obtained using the formula for spectral resolution
            ff((L/2)+1:end) = ff((L/2)+1:end) - fs;  % To comply with Nyquist’s theorem, the sampling frequency is subtracted from the second half of the vector in order to remap the high frequencies to negative frequencies.
            [~,pos]=f_cercana(app,f,ff);  % The system searches within the frequency range for the frequency closest to the one entered by the user
            
            % The complex zero vectors are initialised to sum the waves from the loudspeakers
            onda_array = zeros(1, length(distancias));
            onda_sub = zeros(1, length(distancias));

            if app.n_array>0
                for i_alt= 1:1:app.n_array
                    dx = distancias - app.R_speaker_array(i_alt, 1); % The distance between the source and the measurement point on the X-axis is calculated
                    dy = r(2) - app.R_speaker_array(i_alt, 2); % The distance between the source and the measurement point on the X-axis is calculated
                    dist_alt = sqrt(dx.^2 + dy.^2); % The distance between the loudspeaker and the microphone is calculated using Pythagoras’ theorem
                    dist_alt(dist_alt < 0.01) = 0.01; % If the distance is less than 0.01, it is set to 0.01 to avoid problems with uncertainty calculations.
                    
                    onda_alt=app.ang_resp1m_array(i_alt,pos) .* (exp(-(1i*k*(dist_alt-1))) ./ (dist_alt)) ; % Se aplica la ecuación de la onda esférica junto a la atenuación atmosférica
                    onda_array=onda_array+onda_alt; % The coherent addition of the complex sound pressure is carried out
                end
            end

            if app.n_sub>0
                for i_alt= 1:1:app.n_sub
                    dx = distancias - app.R_speaker_sub(i_alt, 1); % The distance between the source and the measurement point on the X-axis is calculated
                    dy = r(2) - app.R_speaker_sub(i_alt, 2); % The distance between the source and the measurement point on the X-axis is calculated
                    dist_alt = sqrt(dx.^2 + dy.^2); % The distance between the loudspeaker and the microphone is calculated using Pythagoras’ theorem

                    dist_alt(dist_alt < 0.01) = 0.01; % If the distance is less than 0.01, it is set to 0.01 to avoid problems with uncertainty calculations.
                   
                    onda_alt=app.ang_resp1m_sub(i_alt,pos) .* (exp(-(1i*k*(dist_alt-1))) ./ (dist_alt)); % Se aplica la ecuación de la onda esférica junto a la atenuación atmosférica
                    onda_sub=onda_sub+onda_alt; % The coherent addition of the complex sound pressure is carried out
                end
            end
            
            
            onda=onda_array+onda_sub; % The coherent addition of the complex sound pressure is carried out
            SPL=20*log10(abs(onda)); % Linear pressure is converted to sound pressure levels (SPL) in dB

            plot(app.UIEje,distancias, SPL, 'LineWidth', 2); % Sound pressure levels are plotted
            % Labels and the aesthetics of representation
            legend(app.UIEje,"off")
            xlabel(app.UIEje,'Distance from the loudspeaker (m)');
            ylabel(app.UIEje,'SPL');
            title(app.UIEje,'SPL / r (X-Axis)');
            axis(app.UIEje,[min(distancias) max(distancias) max(SPL)-80 max(SPL)+5])  % Adjust the visual scale of the X-axis using the distance vector and the Y-axis from the lowest value to 5 dB above the peak and 80 dB below the maximum
        end

        function SPL_R_y(app,r,f,fs)
            landa=app.c_amb/f; % The wavelength is calculated
            k = 2*pi/landa; % The wave number is calculated espacial
            
            y_coord = r(2);  % The Y coordinate of the microphone’s position is extracted
            if app.n_array > 0
                y_coord = [y_coord, app.R_speaker_array(:, 2)']; % The X-coordinates of all line array speakers are added to the list to calculate the boundaries of the display dynamically
            end
            if app.n_sub > 0
                y_coord = [y_coord, app.R_speaker_sub(:, 2)']; % The X-coordinates of all subwoofers are added to the list to calculate the display boundaries dynamically
            end
            
            % It finds the furthest points and gives them a visual margin of 5 cm
            limite_inf = min(y_coord) - 0.05;
            limite_sup = max(y_coord) + 0.05;
            
             % If the measurement point and the loudspeakers are in line with one another, the minimum values are set to -1 and +1 so that the graph can be plotted
            if limite_inf == limite_sup
                limite_inf = -1;
                limite_sup = 1;
            end

            distancias = limite_inf:0.05:limite_sup; % The spatial vector is created with a 5 cm spacing between the two boundaries


            L = 32768; % Magnitude of the Fourier transform
            ff = (fs/L)*(0:L-1);% Magnitude of the Fourier transform
            ff((L/2)+1:end) = ff((L/2)+1:end) - fs;  % To comply with Nyquist’s theorem, the sampling frequency is subtracted from the second half of the vector in order to remap the high frequencies to negative frequencies.
            [~,pos]=f_cercana(app,f,ff); % The system searches within the frequency range for the frequency closest to the one entered by the user
            
            % The complex zero vectors are initialised to sum the waves from the loudspeakers
            onda_array = zeros(1, length(distancias));
            onda_sub = zeros(1, length(distancias));

            if app.n_array>0
                for i_alt= 1:1:app.n_array
                    dx = r(1) - app.R_speaker_array(i_alt, 1); % The distance between the source and the measurement point on the X-axis is calculated
                    dy = distancias - app.R_speaker_array(i_alt, 2); % The distance between the source and the measurement point on the Y-axis is calculated
                    dist_alt = sqrt(dx.^2 + dy.^2); % The distance between the loudspeaker and the microphone is calculated using Pythagoras’ theorem
                    dist_alt(dist_alt < 0.01) = 0.01; % If the distance is less than 0.01, it is set to 0.01 to avoid problems with uncertainty calculations.
                    
                    onda_alt=app.ang_resp1m_array(i_alt,pos) .* (exp(-(1i*k*(dist_alt-1))) ./ (dist_alt)); % Se aplica la ecuación de la onda esférica junto a la atenuación atmosférica
                    onda_array=onda_array+onda_alt; % The coherent addition of the complex sound pressure is carried out
                end
            end
            
            
            if app.n_sub>0
                for i_alt= 1:1:app.n_sub
                    dx = r(1) - app.R_speaker_sub(i_alt, 1); % The distance between the source and the measurement point on the X-axis is calculated
                    dy = distancias - app.R_speaker_sub(i_alt, 2); % The distance between the source and the measurement point on the Y-axis is calculated
                    dist_alt = sqrt(dx.^2 + dy.^2); % The distance between the loudspeaker and the microphone is calculated using Pythagoras’ theorem

                    dist_alt(dist_alt < 0.01) = 0.01; % If the distance is less than 0.01, it is set to 0.01 to avoid problems with uncertainty calculations.
                   
                    onda_alt=app.ang_resp1m_sub(i_alt,pos) .* (exp(-(1i*k*(dist_alt-1))) ./ (dist_alt)); % Se aplica la ecuación de la onda esférica junto a la atenuación atmosférica
                    onda_sub=onda_sub+onda_alt; % The coherent addition of the complex sound pressure is carried out
                end
            end
            
            
            onda=onda_array+onda_sub; % The coherent addition of the complex sound pressure is carried out
            SPL=20*log10(abs(onda)); % Linear pressure is converted to sound pressure levels (SPL) in dB

            plot(app.UIEje,distancias, SPL, 'LineWidth', 2); % Sound pressure levels are plotted
            % Labels and the aesthetics of representation
            legend(app.UIEje,"off")
            xlabel(app.UIEje,'Distance from the loudspeaker (m)');
            ylabel(app.UIEje,'SPL');
            title(app.UIEje,'SPL / r (Y-Axis)');
            axis(app.UIEje,[min(distancias) max(distancias) max(SPL)-80 max(SPL)+5]) % Adjust the visual scale of the X-axis using the distance vector and the Y-axis from the lowest value to 5 dB above the peak and 80 dB below the maximum
        end
        
        function IR(app, fs, r, R_speaker)
            % Initialise the ‘total’ variable, which will store the value based on the speakers selected in the settings
            if (sum(app.ang_resp1m_array)~=0)
                IR_total=zeros(app.n_speakers, size(app.ang_resp1m_array, 2));
            else
                IR_total=zeros(app.n_speakers, size(app.ang_resp1m_sub, 2));
            end
        
            if app.n_array>0
                IR_total_array=zeros(app.n_array, size(app.ang_resp1m_array, 2)); % Initialise the variable for the response to the drive from the line array loudspeakers.
                resp1m_array=ifft(app.ang_resp1m_array,[],2,'symmetric'); % Convert the complex frequency response to the time domain using the inverse Fourier transform, ensuring that the complex part is 0
                for i_alt = 1:1:app.n_array
                    dx = r(1) - app.R_speaker_array(i_alt, 1); % The distance between the source and the measurement point on the X-axis is calculated
                    dy = r(2) - app.R_speaker_array(i_alt, 2); % The distance between the source and the measurement point on the Y-axis is calculated
                    distancia = sqrt(dx^2 + dy^2); % The distance between the loudspeaker and the microphone is calculated using Pythagoras’ theorem
            
                    if distancia < 0.01
                        distancia = 0.01; % If the distance is less than 0.01, it is set to 0.01 to avoid problems with uncertainty calculations.
                    end
                    
                    retardo=distancia - 1; % As in Maltab the counters start at 1, we subtract 1 from the delay
                    if retardo < 0
                        retardo = 0; % If the delay is less than 0 samples, we set it to 0
                    end
            
                    muestras_retardo = round((retardo / app.c_amb) * fs); % The distance is converted into milliseconds and then into audio samples
                    IR_total_array(i_alt, :) = circshift(resp1m_array(i_alt,:), muestras_retardo) / distancia ; % The response resp1m of the loudspeaker i_alt is shifted in a circular pattern by the calculated number of samples, and we divide this by the distance to take account of the signal attenuation caused by the shift
                end
                IR_total(1:app.n_array,:)=IR_total_array; % The calculated data for the line array speakers is stored
            end

            if app.n_sub>0
                IR_total_sub=zeros(app.n_sub, size(app.ang_resp1m_sub, 2)); % Initialise the variable for the subwoofer speaker response.
                resp1m_sub=ifft(app.ang_resp1m_sub,[],2,'symmetric'); % Convert the complex frequency response to the time domain using the inverse Fourier transform, ensuring that the complex part is 0
                for i_alt = 1:1:app.n_sub
                    dx = r(1) - app.R_speaker_sub(i_alt, 1); % The distance between the source and the measurement point on the X-axis is calculated
                    dy = r(2) - app.R_speaker_sub(i_alt, 2); % The distance between the source and the measurement point on the Y-axis is calculated
                    distancia = sqrt(dx^2 + dy^2); % The distance between the loudspeaker and the microphone is calculated using Pythagoras’ theorem
            
                    if distancia < 0.01
                        distancia = 0.01; % If the distance is less than 0.01, it is set to 0.01 to avoid problems with uncertainty calculations.
                    end
                    
                    retardo=distancia - 1; % As in Maltab the counters start at 1, we subtract 1 from the delay
                    if retardo < 0
                        retardo = 0; % If the delay is less than 0 samples, we set it to 0
                    end
            
                    muestras_retardo = round((retardo / app.c_amb) * fs); % The distance is converted into milliseconds and then into audio samples
                    IR_total_sub(i_alt, :) = circshift(resp1m_sub(i_alt,:), muestras_retardo) / distancia ; % The response resp1m of the loudspeaker i_alt is shifted in a circular pattern by the calculated number of samples, and we divide this by the distance to take account of the signal attenuation caused by the shift
                end
                IR_total(app.n_array+1:app.n_array+app.n_sub,:)=IR_total_sub; % The calculated data for the line array speakers is stored
            end

            t = (0:size(IR_total,2)-1) *1000/ (fs); % A time axis in milliseconds (1000/fs) is generated for display
            hold (app.UIEje,'on');
            for i_alt = 1:app.n_speakers
                plot(app.UIEje,t, IR_total(i_alt,:), 'LineWidth', 1.5);  % It represents each response to the time impulse of all the loudspeakers
                nombres_leyenda{i_alt} = sprintf('Speaker %d (%.1f, %.1f)', i_alt, R_speaker(i_alt,1), R_speaker(i_alt,2));  % Store the names using the format “Speaker i_alt (Speaker X-coordinate to one decimal place, Speaker Y-coordinate to one decimal place)” for the dynamic legend
            end
            legend(app.UIEje,nombres_leyenda); % We display the legend using the stored names
            % Labels and the aesthetics of representation
            grid (app.UIEje,'on'); 
            xlabel(app.UIEje,'Time (ms)');
            ylabel(app.UIEje,'Amplitude');
            title(app.UIEje,'Combined Impulse Response (IR)');

            limx=size(IR_total,2)*1000/fs; % The size of the axes is calculated dynamically
            xlim(app.UIEje, [0 limx]);
            ylim(app.UIEje,"auto");
            hold(app.UIEje,'off');
        end
        
        function mapa_calor_promediado(app)
            % The lower and upper cut-off frequencies of the band to be averaged are calculated
            f1 = app.f * 2^(-1/(2*app.avg)); 
            f2 = app.f * 2^(1/(2*app.avg));
            [~,pos1]=f_cercana(app,f1,app.F); % The system searches within the frequency range for the frequency closest to the one entered by the user
            [~,pos2]=f_cercana(app,f2,app.F); % The system searches within the frequency range for the frequency closest to the one entered by the user
            vector_f=app.F(pos1:pos2); % The frequencies to be used in the calculations and for averaging are stored

            H_mallado_avg_suma=zeros(size(app.mesh_x,1),size(app.mesh_y,2), 'like', complex(single(0))); % The matrix that will accumulate the values for calculating the average energy is initialised with complex zeros
            % The propagation at all points in the mesh is calculated in an iterative loop for each frequency within the band for the two types of loudspeaker
            for i_frec=1:1:size(vector_f,2)
                if app.n_array > 0
                    H_mallado_avg_array = calc_h_malla(app, app.IR_array, app.targetAngles, app.n_array, app.R_speaker_array, vector_f(i_frec), app.fs, app.ang_offset, app.pressure, app.temperature, app.relative_humidity);
                else
                    H_mallado_avg_array = 0; % If there are no speakers of this type, the variable is set to 0
                end

                if app.n_sub > 0
                    H_mallado_avg_sub = calc_h_malla(app, app.IR_sub, app.targetAngles, app.n_sub, app.R_speaker_sub, vector_f(i_frec), app.fs, app.ang_offset, app.pressure, app.temperature, app.relative_humidity);
                else
                    H_mallado_avg_sub = 0; % If there are no speakers of this type, the variable is set to 0
                end
                H_mallado_avg=H_mallado_avg_array+H_mallado_avg_sub; % The results from the two types of loudspeakers are added together
                H_mallado_avg_suma=H_mallado_avg_suma+abs(H_mallado_avg).^2; % It sums the energy (squared magnitude)
            end   
            
            H_mallado_avg_spl=10*log10(abs(H_mallado_avg_suma/size(vector_f,2))); % It calculates the arithmetic mean of the energy and converts it to dB (10*log10 because the matrix is squared)

            SPL_ordenado = sort(H_mallado_avg_spl(:), 'descend'); % Sort the SPL values from highest to lowest
            indice_max = max(1, round(length(SPL_ordenado) * 0.01)); % The position corresponding to the first highest value is calculated
            pico_max_conjunto = SPL_ordenado(indice_max); % The SPL value at that position is retrieved
            H_mallado_avg_spl=H_mallado_avg_spl-pico_max_conjunto; % The entire heat map is normalised
           
            suelo = ones(size(H_mallado_avg_spl)) * -1000; % Create a floor at elevation -1000 to project 2D textures and display the speaker and microphone on top of the heat map
            
            % Labels and the aesthetics of representation
            hold (app.UIEje,'on');
            surf(app.UIEje,app.mesh_x,app.mesh_y,suelo,H_mallado_avg_spl); % It represents the energy surface
            shading (app.UIEje,'interp'); % Remove the grid and soften the colours
            view(app.UIEje,2); % 2D top-down view
            colormap(app.UIEje,'turbo'); % Change the colour palette
            clim(app.UIEje,[-app.range_db, 0]); % Limits the scale between the selected dB Range value and 0 dB
            colorbar(app.UIEje); % Show the colour key
            hold (app.UIEje,'off');

        end

        function mapa_calor_3dB_promediado(app)
            % The lower and upper cut-off frequencies of the band to be averaged are calculated
            f1 = app.f * 2^(-1/(2*app.avg));
            f2 = app.f * 2^(1/(2*app.avg));
            [~,pos1]=f_cercana(app,f1,app.F); % The system searches within the frequency range for the frequency closest to the one entered by the user
            [~,pos2]=f_cercana(app,f2,app.F); % The system searches within the frequency range for the frequency closest to the one entered by the user
            vector_f=app.F(pos1:pos2); % The frequencies to be used in the calculations and for averaging are stored

            H_mallado_avg=zeros(size(app.mesh_x,1),size(app.mesh_y,2), 'like', complex(single(0))); % The matrix that will accumulate the values for calculating the average energy is initialised with complex zeros
            
            if app.n_sub>0
                % The propagation at all points in the mesh is calculated in an iterative loop for each frequency within the subwoofer’s frequency band
                for i_frec=1:1:size(vector_f,2)
                    H_mallado=calc_h_malla(app, app.IR_sub,app.targetAngles,app.n_sub,app.R_speaker_sub,vector_f(i_frec),app.fs,app.ang_offset,app.pressure,app.temperature,app.relative_humidity);
                    H_mallado_avg=H_mallado_avg+abs(H_mallado).^2; % It sums the energy (squared magnitude)
                end
                H_mallado_avg_sub=H_mallado_avg; % The calculated result is stored

            else
                H_mallado_avg_sub=0; % If there are no speakers of this type, the variable is set to 0
            end

            if app.n_array>0
                % The propagation at all points in the mesh is calculated in an iterative loop for each frequency within the line array speaker band
                for i_frec=1:1:size(vector_f,2)
                    H_mallado=calc_h_malla(app, app.IR_array,app.targetAngles,app.n_array,app.R_speaker_array,vector_f(i_frec),app.fs,app.ang_offset,app.pressure,app.temperature,app.relative_humidity);
                    H_mallado_avg=H_mallado_avg+abs(H_mallado).^2; % It sums the energy (squared magnitude)
                end
                H_mallado_avg_array=H_mallado_avg; % The calculated result is stored
            else
                H_mallado_avg_array=0; % If there are no speakers of this type, the variable is set to 0
            end
            
            H_mallado_avg=H_mallado_avg_array+H_mallado_avg_sub; % The consistent sum is calculated
            H_mallado_avg_spl=10*log10(abs(H_mallado_avg/size(vector_f,2))); % It calculates the arithmetic mean of the energy and converts it to dB (10*log10 because the matrix is squared)
            
            SPL_ordenado = sort(H_mallado_avg_spl(:), 'descend'); % Sort the SPL values from highest to lowest
            indice_max = max(1, round(length(SPL_ordenado) * 0.01)); % The position corresponding to the first highest value is calculated
            pico_max_conjunto = SPL_ordenado(indice_max); % The SPL value at that position is retrieved
            H_mallado_avg_spl=H_mallado_avg_spl-pico_max_conjunto; % The entire heat map is normalised

            H_mallado_avg_spl_3db=zeros(size(H_mallado_avg_spl,1),size(H_mallado_avg_spl,2)); % The matrix that will accumulate the values for calculating the average energy is initialised with complex zeros
            aux_3db=fliplr(0:-3:-app.range_db); % Create a vector with the permitted ‘steps’: -40, -37, -34… up to 2
            
            for i_prueba_x=1:1:size(H_mallado_avg_spl,1)
                for i_prueba_y= 1:1:size(H_mallado_avg_spl,2)
                    [~,pos]=min(abs(aux_3db-H_mallado_avg_spl(i_prueba_x,i_prueba_y))); % Find the aux_3db step that most closely approximates the actual value of that coordinate
                    H_mallado_avg_spl_3db(i_prueba_x,i_prueba_y)=aux_3db(pos); % Overwrite the actual continuous value with the value rounded to the nearest 3dB block
                end
            end

            suelo = ones(size(H_mallado_avg_spl_3db)) * -1000; % Create a floor at elevation -1000 to project 2D textures and display the speaker and microphone on top of the heat map
            
            % Labels and the aesthetics of representation
            cla(app.UIEje);
            hold (app.UIEje,'on');
            surf(app.UIEje,app.mesh_x,app.mesh_y,suelo,H_mallado_avg_spl_3db); % It represents the energy surface
            shading (app.UIEje,"interp"); % Remove the grid and soften the colours
            view(app.UIEje,2); % 2D top-down view
            colormap(app.UIEje,'turbo'); % Change the colour palette (try ‘jet’ or ‘turbo’)
            clim(app.UIEje,[-app.range_db, 0]); % Limita la escala entre el valor de range_db y 0 dB
            colorbar(app.UIEje,"south"); % Show the colour key
            hold(app.UIEje,'off');
        end

        function IR_angulo(app,f,fs)
            frec_baja = 250; % Low-frequency reference frequency (omnidirectional)
            frec_alta = 4000; % High-frequency reference frequency (directive)
        
            L=32768; % Magnitude of the Fourier transform
            ff=(fs/L)*(0:L-1); % The frequency axis is obtained using the formula for spectral resolution
            ff((L/2)+1:end) = ff((L/2)+1:end) - fs; % To comply with Nyquist’s theorem, the sampling frequency is subtracted from the second half of the vector in order to remap the high frequencies to negative frequencies.
        
            [~, idx_baja] = f_cercana(app,frec_baja, ff); % The frequency closest to the specified frequency is sought within the frequency range
            [~, idx_alta] = f_cercana(app,frec_alta, ff); % The frequency closest to the specified frequency is sought within the frequency range
            [~, idx_f] = f_cercana(app,f, ff); % The system searches within the frequency range for the frequency closest to the one entered by the user
           
            if app.n_sub>0
                % Extracts the specific columns from the interpolated response of the subwoofer speakers for the desired frequencies and converts them from complex magnitudes to dB
                energia_baja_dB_sub = 20*log10(abs(app.IR_sub(:, idx_baja)));
                energia_alta_dB_sub = 20*log10(abs(app.IR_sub(:, idx_alta)));
                energia_frec_dB_sub = 20*log10(abs(app.IR_sub(:, idx_f)));
                % The three energy distributions are shown in different colours to make them easy to distinguish, and are represented by a dotted line.
                plot(app.UIEje,app.targetAngles, energia_baja_dB_sub, 'b', 'LineWidth', 2,'LineStyle','-.','DisplayName', [num2str(frec_baja) ' Hz (Sub)']);
                hold (app.UIEje,'on');
                plot(app.UIEje,app.targetAngles, energia_alta_dB_sub, 'r', 'LineWidth', 2,'LineStyle','-.','DisplayName', [num2str(f) ' Hz (Sub)']);
                hold (app.UIEje,'on');
                plot(app.UIEje,app.targetAngles, energia_frec_dB_sub, 'g', 'LineWidth', 2,'LineStyle','-.','DisplayName', [num2str(frec_alta) ' Hz (Sub)']); 
            end

            if app.n_array>0
                % Extracts the specific columns from the interpolated response of the line array loudspeakers for the desired frequencies and converts them from complex magnitudes to dB
                energia_baja_dB_array = 20*log10(abs(app.IR_array(:, idx_baja)));
                energia_alta_dB_array = 20*log10(abs(app.IR_array(:, idx_alta)));
                energia_frec_dB_array = 20*log10(abs(app.IR_array(:, idx_f)));
                % The three energy distributions are shown in different colours so that they can be easily distinguished
                plot(app.UIEje,app.targetAngles, energia_baja_dB_array, 'b', 'LineWidth', 2,'DisplayName', [num2str(frec_baja) ' Hz (Array)']);
                hold (app.UIEje,'on');
                plot(app.UIEje,app.targetAngles, energia_alta_dB_array, 'r', 'LineWidth', 2,'DisplayName', [num2str(f) ' Hz (Array)']);
                hold (app.UIEje,'on');
                plot(app.UIEje,app.targetAngles, energia_frec_dB_array, 'g', 'LineWidth', 2,'DisplayName', [num2str(frec_alta) ' Hz (Array)']);
            end
             % Labels and the aesthetics of representation
            grid (app.UIEje,'on');
            xlabel(app.UIEje,'Angle (degrees)');
            ylabel(app.UIEje,'Magnitude SPL (dB)');
            title(app.UIEje,'SPL with respect to the angle');
            legend(app.UIEje,"show"); % A legend is created showing the colours corresponding to the graphs for the three frequencies
            xlim(app.UIEje,[0 360]); % As shown for the radiation, it will be limited to between 0 and 360
            ylim(app.UIEje,'tight') % The Y-axis is adjusted
        end
        
        function plot_grafica(app)
            cla(app.UIEje); % The data shown on the axis is cleared
            seleccion = app.GraficaDropDown.Value; % The value of the graph to be plotted, as selected by the user, is retrieved
            colorbar(app.UIEje,"off"); % The colour bar is disabled
            switch seleccion
                case 'Heat Map'
                    app.range_db=round(app.dBrangeSlider.Value); % The dB range value is obtained
                    app.PanelM_F.Visible='off'; % The transfer function plot panel is disabled
                    app.imagen_logo.Visible='off'; % The photo of the logos is hidden
                    app.UIEje.Visible='on'; % The display axis is activated
                    app.PanelProm.Visible='on'; % The averaging drop-down menu is activated
                    legend (app.UIEje,'off'); % The caption (if any) is disabled
                    cla(app.UIEje); % The data shown on the axis is cleared
                    mapa_calor_promediado(app); % The heatmap function is called
                    mapa(app,app.r_microphone,app.graph); % The configuration map function is called
                    set(app.UIEje.Children, 'HitTest', 'off'); % Select all elements drawn within the y-axis and disable the user from tapping on the graph
                case 'Heat Map (3 dB)'  
                    app.range_db=round(app.dBrangeSlider.Value); % The dB range value is obtained
                    app.PanelM_F.Visible='off'; % The transfer function plot panel is disabled
                    app.imagen_logo.Visible='off'; % The photo of the logos is hidden
                    legend (app.UIEje,'off'); % The caption (if any) is disabled
                    app.UIEje.Visible='on'; % The display axis is activated
                    app.PanelProm.Visible='on'; % The averaging drop-down menu is activated
                    mapa_calor_3dB_promediado(app);% The heatmap function is called
                    mapa(app,app.r_microphone,app.graph); % The configuration map function is called
                    set(app.UIEje.Children, 'HitTest', 'off'); % Select all elements drawn within the y-axis and disable the user from tapping on the graph
                case 'Magnitude and Phase'
                    %PanelM_F
                    app.PanelM_F.Visible='on'; % The display panel is activated
                    app.imagen_logo.Visible='off'; % The photo of the logos is hidden
                    app.UIEje.Visible='off'; % The display axis is disabled
                    app.PanelProm.Visible='off'; % The averaging drop-down menu is disabled
                    representacionM_F(app,app.Y,app.F); % The function is called to represent the magnitude and phase
                case 'Impulse Response'
                    app.PanelM_F.Visible='off'; % The display panel is disabled
                    app.imagen_logo.Visible='off'; % The photo of the logos is hidden
                    app.PanelProm.Visible='off'; % The averaging drop-down menu is disabled
                    app.UIEje.Visible='on'; % The display axis is activated
                    IR(app, app.fs, app.r_microphone, app.R_speaker); % The function is called to plot the IR
                case 'SPL / distance (X-Axis)'
                    app.PanelM_F.Visible='off'; % The display panel is disabled
                    app.imagen_logo.Visible='off'; % The photo of the logos is hidden
                    app.PanelProm.Visible='off'; % The averaging drop-down menu is disabled
                    app.UIEje.Visible='on'; % The display axis is activated
                    SPL_R_x(app,app.r_microphone,app.f,app.fs); % The function is called to calculate the SPL with respect to r on the X-axis
                case 'SPL / distance (Y-Axis)'
                    app.PanelM_F.Visible='off'; % The display panel is disabled
                    app.imagen_logo.Visible='off'; % The photo of the logos is hidden
                    app.PanelProm.Visible='off'; % The averaging drop-down menu is disabled
                    app.UIEje.Visible='on'; % The display axis is activated
                    SPL_R_y(app,app.r_microphone,app.f,app.fs); % The function is called to calculate the SPL with respect to r on the Y-axis
                case 'SPL / θ'                    
                    app.PanelM_F.Visible='off'; % The display panel is disabled
                    app.imagen_logo.Visible='off'; % The photo of the logos is hidden
                    app.PanelProm.Visible='off'; % The averaging drop-down menu is disabled
                    app.UIEje.Visible='on'; % The display axis is activated
                    IR_angulo(app,app.f,app.fs); % The function is called to calculate the SPL with respect to the angle of rotation
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configuring the main application window by changing the name and logo 
            app.PredicciondelcomportamientoacsticodeAltavocesUIFigure.Name = 'Speaker Configuration Simulator';

            % To display the logos from the image and the interface without having to send the photo along with the file, the image is embedded in the interface code
            % The image is encoded using Base64 to avoid incompatibilities between the multimedia file format and the code; this code is then copied and stored in variables as a text string
            codigo_logo_app='';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
            codigo_foto_logos='';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
            
            % The text is decoded into binary data using the `base64decode` function
            bytes_logo=matlab.net.base64decode(codigo_logo_app);
            bytes_foto=matlab.net.base64decode(codigo_foto_logos);
            
            % A temporary file is created and stored in the PC’s memory, and the computer itself will delete it
            ruta_temp=[tempname,'.png'];
            ruta_temp_2=[tempname,'.png'];
            
            % The file is opened for binary writing (“write binary”)
            fid1=fopen(ruta_temp,'wb');
            fid2=fopen(ruta_temp_2,'wb');

            % The photo data is written to each file
            fwrite(fid1,bytes_foto,'uint8');
            fwrite(fid2,bytes_logo,'uint8');

            % The files are closed because nothing else is going to be written to them
            fclose(fid1);
            fclose(fid2);

            % The function is instructed to read the relevant file in each case
            app.imagen_logo.ImageSource=ruta_temp; 
            app.PredicciondelcomportamientoacsticodeAltavocesUIFigure.Icon = ruta_temp_2;

            % Group all the graphical elements for all the speakers into a single variable to make it easier to manage their visibility
            app.SpeakersBox = {app.Altavoz1EditFieldLabel,app.Altavoz1X,app.Altavoz1Y,app.Altavoz1_grados,app.Tipo_alt1;app.Altavoz2EditFieldLabel,app.Altavoz2X,app.Altavoz2Y,app.Altavoz2_grados,app.Tipo_alt2;app.Altavoz3EditFieldLabel,app.Altavoz3X,app.Altavoz3Y,app.Altavoz3_grados,app.Tipo_alt3;app.Altavoz4EditFieldLabel,app.Altavoz4X,app.Altavoz4Y,app.Altavoz4_grados,app.Tipo_alt4;app.Altavoz5EditFieldLabel,app.Altavoz5X,app.Altavoz5Y,app.Altavoz5_grados,app.Tipo_alt5;app.Altavoz6EditFieldLabel,app.Altavoz6X,app.Altavoz6Y,app.Altavoz6_grados,app.Tipo_alt6};

            max_n_speakers=size(app.SpeakersBox,1);
            estado = 'off';
            % Hide all the graphical elements for the speakers except for the first row, because the initial value of the ‘Number of speakers’ spinner is 1
            for i = 2:max_n_speakers
                app.SpeakersBox{i, 1}.Visible = estado; % Text
                app.SpeakersBox{i, 2}.Visible = estado; % X-coordinate
                app.SpeakersBox{i, 3}.Visible = estado; % Y-coordinate
                app.SpeakersBox{i, 4}.Visible = estado; % Angle
                app.SpeakersBox{i, 5}.Visible = estado; % Speaker Type

            end
            
            % Set which panels and logos are visible when the application starts
            app.PanelM_F.Visible='on';
            app.dBrangeSlider.Visible='on';
            app.dBrangeSliderLabel.Visible='on';
            app.imagen_logo.Visible='on';
        end

        % Value changed function: NumberofspeakersSpinner
        function NumberofspeakersSpinnerValueChanged(app, event)
            app.n_speakers = round((app.NumberofspeakersSpinner.Value)); % The number of speakers selected by the user is read out
            max_n_speakers=size(app.SpeakersBox,1); % Maximum number of speakers: 6
            % Go through the entire table of graphical components, toggling their visibility on and off
            for i = 1:max_n_speakers
                % If the number in this row is less than or equal to the order, we tick it
                if i <= app.n_speakers
                    estado = 'on';
                else % If it goes over, we’ll switch it off
                    estado = 'off';
                end
                app.SpeakersBox{i, 1}.Visible = estado; % Text
                app.SpeakersBox{i, 2}.Visible = estado; % X-coordinate
                app.SpeakersBox{i, 3}.Visible = estado; % Y-coordinate
                app.SpeakersBox{i, 4}.Visible = estado; % Angle
                app.SpeakersBox{i, 5}.Visible = estado; % Speaker Type
            end 
        end

        % Button pushed function: SimulateRepresentButton
        function SimulateRepresentButtonPushed(app, event)
            % Check whether the user has selected ‘Line Array’ or ‘Subwoofers’ from the speaker type drop-down menu
            if isequal(app.audiopath_array, []) && isequal(app.audiopath_sub, [])
                uialert(app.PredicciondelcomportamientoacsticodeAltavocesUIFigure, 'Select a folder containing the speaker’s audio files.', 'Warning', 'Icon', 'warning');
                return; % If the user cancels or closes the tab without filling in the details, the function is interrupted and does nothing
            end

            % The variables are initialised to 0
            app.n_array=0;
            app.n_sub=0;
            app.n_speakers = round((app.NumberofspeakersSpinner.Value)); 
            app.R_speaker=zeros(app.n_speakers,2);
            app.R_speaker_array=[];
            app.R_speaker_sub=[];
            app.r_microphone=[app.MicroX.Value,app.MicroY.Value];

            app.ang_offset=zeros(1,app.n_speakers);
            for i_alt=1:app.n_speakers

                app.R_speaker(i_alt,:)=[(app.SpeakersBox{i_alt,2}.Value),(app.SpeakersBox{i_alt,3}.Value)]; % The spatial positions of all the speakers are stored
                app.ang_offset(1,i_alt)=(app.SpeakersBox{i_alt,4}.Value); % The spatial tilt angles of all the speakers are stored
                % Check whether the user has selected ‘Line Array’ or ‘Subwoofers’ from the speaker type drop-down menu
                if(strcmp(app.SpeakersBox{i_alt,5}.Value,"Line Array"))
                    if isequal(app.audiopath_array, []) || isequal(app.audiopath_array, 0)
                        % Check whether the user has selected the folder containing the audio files for that type of speaker
                        uialert(app.PredicciondelcomportamientoacsticodeAltavocesUIFigure, 'Select the folder containing the audio files for the line array speaker.', 'Warning', 'Icon', 'warning');
                    return;
                    end
                    app.n_array=app.n_array+1; % Increase the speaker count for the ‘Line Array’ group
                    app.ang_array(app.n_array)=(app.SpeakersBox{i_alt,4}.Value); % Stores the physical tilt angle of the speaker entered by the user for the ‘Line Array’ speakers
                    app.R_speaker_array(app.n_array,:)=[(app.SpeakersBox{i_alt,2}.Value),(app.SpeakersBox{i_alt,3}.Value)]; % Stores the spatial position of the ‘Line Array’ speakers
                else
                    if isequal(app.audiopath_sub, []) || isequal(app.audiopath_array, 0)
                        % Check whether the user has selected the folder containing the audio files for that type of speaker
                        uialert(app.PredicciondelcomportamientoacsticodeAltavocesUIFigure, 'Select the folder containing the audio files for the subwoofer.', 'Warning', 'Icon', 'warning');
                        return; % If the user cancels or closes the tab without filling in the details, the function is interrupted and does nothing
                    end
                    app.n_sub=app.n_sub+1; % Increase the speaker count for the ‘Sub-bass’ group
                    app.ang_sub(app.n_sub)=(app.SpeakersBox{i_alt,4}.Value); % Stores the physical tilt angle of the ‘sub-bass’ speakers, as entered by the user
                    app.R_speaker_sub(app.n_sub,:)=[(app.SpeakersBox{i_alt,2}.Value),(app.SpeakersBox{i_alt,3}.Value)]; % Store the spatial position of the ‘sub-bass’ speakers
                end
            end
            
            app.f=(app.Freq.Value); % The frequency value selected by the user is retrieved
            if app.n_array > 0
                ruta_0= fullfile(app.audiopath_array, '0.wav'); % The path to the 0-degree file is obtained
            else
                ruta_0= fullfile(app.audiopath_sub, '0.wav'); % The path to the 0-degree file is obtained
            end

            [~,app.fs]=audioread(ruta_0); % The sample rate of the 0-degree file is obtained
            % The representation values from the application are retrieved and stored
            offset=[app.OffsetX.Value,app.OffsetY.Value]; 
            ejes=[app.EjeX.Value,app.EjeY.Value];
            app.graph=[offset;ejes];
            
            % The atmospheric conditions for the application are obtained and stored
            app.pressure=app.Presion.Value;
            app.temperature=app.Temp.Value;
            app.relative_humidity=(app.Humedad.Value);
            
            % The spatial resolution values are obtained from the graphs
            app.res=[(app.ResolucionX.Value),(app.ResolucionY.Value)];
            
            % Se recupera el valor seleccionado por el usuario en el menú desplegable y se actualiza la variable de promediado `app.avg`.
            seleccion_prom=app.AveragedDropDown.Value;
            switch seleccion_prom   
                case '1'
                    app.avg=1;
                case '1/3'    
                    app.avg=3;
                case '1/6'
                    app.avg=6;
                case '1/12'
                    app.avg=12;
                case '1/24'
                    app.avg=24;
            end 
            
            app.targetAngles = 0:0.5:359.5; % The angle vector to be interpolated is created
            if app.n_array > 0 
                [app.FFT_ang_array, angulos_array, ~] = matriz_fft_angulo(app,app.route_array); % Data on directional measurements and the angles of the line array speakers are obtained
                app.IR_array = interpolateIRAngle(app,app.FFT_ang_array, angulos_array, app.targetAngles, 'pchip'); % The response is interpolated in 0.5-degree increments
                [app.Y_array, app.F,app.ang_resp1m_array]=calc_Ir_microphone(app,app.r_microphone,app.R_speaker_array,app.ang_offset,app.FFT_ang_array,app.n_array,angulos_array,app.fs,app.pressure,app.temperature,app.relative_humidity); % The function is called to calculate the impulse response captured by the microphone on the line array speakers
            else
                % If there are no speakers of this type, the variables remain at 0
                app.Y_array=0; 
                app.ang_resp1m_array=0;
            end
            
            if app.n_sub > 0
                [app.FFT_ang_sub, angulos_sub, ~] = matriz_fft_angulo(app,app.route_sub); % Data on directional measurements and the angles of the subwoofer speakers is obtained
                app.IR_sub = interpolateIRAngle(app,app.FFT_ang_sub, angulos_sub, app.targetAngles, 'pchip'); % The response is interpolated in 0.5-degree increments
                [app.Y_sub, app.F,app.ang_resp1m_sub]=calc_Ir_microphone(app,app.r_microphone,app.R_speaker_sub,app.ang_offset,app.FFT_ang_sub,app.n_sub,angulos_sub,app.fs,app.pressure,app.temperature,app.relative_humidity); % The function is called to calculate the impulse response captured by the subwoofer’s microphone
            else
                % If there are no speakers of this type, the variables remain at 0
                app.Y_sub=0; 
                app.ang_resp1m_sub=0;
            end
            [app.mesh_x,app.mesh_y]=mallado(app,app.res,app.graph); % The meshing function is called to obtain the data for the spatial representation area
            app.Y=app.Y_array+app.Y_sub; % The values obtained from the two types of loudspeakers are added together

            plot_grafica(app); % The function is called to plot the selected graph
        end

        % Value changed function: GraficaDropDown
        function GraficaDropDownValueChanged(app, event)
            % If the configuration has not been simulated and the data obtained has not been saved, no graph will be displayed
            if ~isempty(app.Y)
                plot_grafica(app); % If the vector contains data, the graphs are plotted
            end
        end

        % Menu selected function: ExportCurrentChartMenu
        function ExportCurrentChartMenuSelected(app, event)
            % A window will open on the user’s own computer, where they can select the path and name for saving the file
            [file, path] = uiputfile('*.pdf', 'Save graph as...', 'simulation.pdf');
            if isequal(file, 0) || isequal(path, 0)
                return; % If the user cancels or closes the tab without filling in the details, the function is interrupted and does nothing
            end

            ruta_completa = fullfile(path, file); % The full save path is created, including the name of the selected file
            drawnow; % Display the graph if it is currently being generated
            pause(0.5); % It waits half a second for the display to update correctly
            seleccion =  app.GraficaDropDown.Value; % The value from the drop-down menu of the selected graph is retrieved
            if(strcmp(seleccion,'Magnitude and Phase') == 1)
                exportgraphics(app.PanelM_F, ruta_completa); % Export the graph from the visualisation panel
            else  
                % As this is an axis within the interface, if you run `exportgraphics(app.UIEje, full_path);`, you will obtain a screenshot of the entire interface, including the gaps in the side panels
                fig_oculta = figure('Visible', 'off', 'Color', 'w','Position', [100, 100, 1200, 800]); % A standard MATLAB window is created, but it is invisible and has a white background
                ax_copia = copyobj(app.UIEje, fig_oculta); % An exact clone of your axes (UIAxis) is created within this new shape
                ax_copia.Units = 'normalized'; % It adjusts the copied image so that it expands to fill the entire page
                ax_copia.Position = [0.15, 0.15, 0.7, 0.75];
                
                % If it is a heat map or a 3 dB heat map, a specific copy is generated to display the colour bar; and to reduce the computational cost of exporting all this data in ‘vector’ format, it is exported as an image in a PDF.
                if contains(seleccion, 'Heat Map')
                    colormap(ax_copia, 'turbo');
                    clim(ax_copia, [-app.range_db, 0]); % Limit the colour bar
                    colorbar(ax_copia, 'southoutside'); % We position the bar below the graph and stretch it out
                    exportgraphics(ax_copia, ruta_completa,'ContentType', 'image', 'Resolution', 500); 
                else
                    exportgraphics(ax_copia, ruta_completa, 'ContentType', 'vector'); % The other graphics are exported as ‘vector’ files, which ensures they are of much higher quality and do not become pixelated
                end
                delete(fig_oculta); % The copy is deleted
            end
            
            uialert(app.PredicciondelcomportamientoacsticodeAltavocesUIFigure, 'The graph has been exported successfully.', 'Saved successfully', 'Icon', 'success'); % Once this process is complete, a pop-up window appears informing the user that the process has been completed successfully
        end

        % Button down function: UIEje
        function UIEjeButtonDown(app, event)
            % When you click on the heatmap, the exact point (in 3D) where the user has clicked is recorded. Index (1) corresponds to the X-coordinate and index (2) to the Y-coordinate.
            X_nueva = event.IntersectionPoint(1);
            Y_nueva = event.IntersectionPoint(2);
            
            % The microphone position value is updated
            app.MicroX.Value=X_nueva;
            app.MicroY.Value=Y_nueva;
            
            % If a point has already been drawn, it checks whether the variable “app.microphone_pointer” contains a valid graphic object and deletes it to replace it with the new point, thereby preventing the point display from becoming cluttered.
            if isgraphics(app.microphone_pointer)
                delete(app.microphone_pointer);
            end

            % We draw the new point using the circle shape (“o”) and store the result of the drawing in the variable “app.microphone_pointer”. This way, the next time we click, the “if” block will know which object to delete.
            hold (app.UIEje,"on");
            app.microphone_pointer=plot(app.UIEje,X_nueva,Y_nueva,'o','MarkerSize', 11, 'MarkerFaceColor', [0.9 0.9 0.9],'MarkerEdgeColor', 'black');
        end

        % Value changed function: AveragedDropDown
        function AveragedDropDownValueChanged(app, event)
            % Se recupera el valor seleccionado por el usuario en el menú desplegable y se actualiza la variable de promediado `app.avg`.
            seleccion_prom = app.AveragedDropDown.Value;
            switch seleccion_prom   
                case '1'
                    app.avg=1;
                case '1/3'    
                    app.avg=3;
                case '1/6'
                    app.avg=6;
                case '1/12'
                    app.avg=12;
                case '1/24'
                    app.avg=24;
            end 
            plot_grafica(app); % The selected graph will be displayed
        end

        % Button pushed function: LineArraySpeakersButton
        function LineArraySpeakersButtonPushed(app, event)
            app.audiopath_array = uigetdir(pwd, 'Select the folder containing the line array loudspeaker’s audio files'); % Abre un menú del ordenador para que el usuario seleccione la carpeta donde se encuentran los archivos de audio de la respuesta al impulso

            % Check whether the user selected a folder or cancelled
            if isequal(app.audiopath_array, 0)
                uialert(app.PredicciondelcomportamientoacsticodeAltavocesUIFigure, 'No folder has been selected.', 'Warning', 'Icon', 'warning');
                return; % If the user cancels or closes the tab without selecting a folder, the function is interrupted and does nothing
            end
            
            app.route_array = fullfile(app.audiopath_array); % The full path of the selected folder is created

        end

        % Menu selected function: ExportIRtxtMenu
        function ExportIRtxtMenuSelected(app, event)
            [file, path] = uiputfile('*.txt', 'Save IR as...', 'simulation.txt'); % A window will open on the user’s own computer, where they can select the path and name for saving the file
            if isequal(file, 0) || isequal(path, 0)
                return; % If the user cancels or closes the tab without filling in the details, the function is interrupted and does nothing
            end
            ruta_completa = fullfile(path, file); % The full save path is created, including the name of the selected file
            writematrix(app.H_eq',ruta_completa); % Export the array of calculated signal values, following the application of the spherical wave equation and atmospheric attenuation, in column format to the selected path and with the selected filename

            uialert(app.PredicciondelcomportamientoacsticodeAltavocesUIFigure, 'The IR has been exported successfully.', 'Saved successfully', 'Icon', 'success'); % Once this process is complete, a pop-up window appears informing the user that the process has been completed successfully
        end

        % Button pushed function: SubwooferSpeakersButton
        function SubwooferSpeakersButtonPushed(app, event)
            app.audiopath_sub = uigetdir(pwd, 'Select the folder containing the subwoofer’s audio files'); % Abre un menú del ordenador para que el usuario seleccione la carpeta donde se encuentran los archivos de audio de la respuesta al impulso

            % Check whether the user selected a folder or cancelled
            if isequal(app.audiopath_sub, 0)
                uialert(app.PredicciondelcomportamientoacsticodeAltavocesUIFigure, 'No folder has been selected.', 'Warning', 'Icon', 'warning');
                return; % If the user cancels or closes the tab without selecting a folder, the function is interrupted and does nothing
            end
            
            app.route_sub = fullfile(app.audiopath_sub); % The full path of the selected folder is created

        end

        % Value changed function: dBrangeSlider
        function dBrangeSliderValueChanged(app, event)
            app.range_db = app.dBrangeSlider.Value; % The value selected by the user from the slider is retrieved and the `range_db` variable is updated
            plot_grafica(app); % The selected graph is redrawn to update this value in the heatmaps.
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.PredicciondelcomportamientoacsticodeAltavocesUIFigure = uifigure('Visible', 'off');
            app.PredicciondelcomportamientoacsticodeAltavocesUIFigure.Name = 'Predicciñon del comportamiento acústico de Altavoces';
            app.FileMenu = uimenu(app.PredicciondelcomportamientoacsticodeAltavocesUIFigure);
            app.FileMenu.Text = 'File';
            app.ExportCurrentChartMenu = uimenu(app.FileMenu);
            app.ExportCurrentChartMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportCurrentChartMenuSelected, true);
            app.ExportCurrentChartMenu.Separator = 'on';
            app.ExportCurrentChartMenu.Accelerator = 'S';
            app.ExportCurrentChartMenu.Text = 'Export Current Chart';
            app.ExportIRtxtMenu = uimenu(app.FileMenu);
            app.ExportIRtxtMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportIRtxtMenuSelected, true);
            app.ExportIRtxtMenu.Separator = 'on';
            app.ExportIRtxtMenu.Accelerator = 't';
            app.ExportIRtxtMenu.Text = 'Export IR (.txt)';
            app.GridLayout = uigridlayout(app.PredicciondelcomportamientoacsticodeAltavocesUIFigure);
            app.UIEje = uiaxes(app.GridLayout);
            xlabel(app.UIEje, 'X')
            ylabel(app.UIEje, 'Y')
            zlabel(app.UIEje, 'Z')
            app.UIEje.ButtonDownFcn = createCallbackFcn(app, @UIEjeButtonDown, true);
            colormap(app.UIEje, 'turbo')
            app.PanelBotones = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.PanelBotones);
            app.NumberofspeakersSpinnerLabel = uilabel(app.GridLayout2);
            app.NumberofspeakersSpinnerLabel.Text = 'Number of speakers';
            app.NumberofspeakersSpinner = uispinner(app.GridLayout2);
            app.NumberofspeakersSpinner.Limits = [1 6];
            app.NumberofspeakersSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofspeakersSpinnerValueChanged, true);
            app.NumberofspeakersSpinner.Value = 1;
            app.Altavoz1X = uieditfield(app.GridLayout2, 'numeric');
            app.Altavoz1Y = uieditfield(app.GridLayout2, 'numeric');
            app.Altavoz1_grados = uieditfield(app.GridLayout2, 'numeric');
            app.Altavoz1_grados.Limits = [-360 360];
            app.Altavoz1EditFieldLabel = uilabel(app.GridLayout2);
            app.Altavoz1EditFieldLabel.Text = 'Speaker 1';
            app.XLabel = uilabel(app.GridLayout2);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.GridLayout2);
            app.YLabel.Text = 'Y';
            app.grados = uilabel(app.GridLayout2);
            app.grados.Text = 'θº';
            app.Altavoz2X = uieditfield(app.GridLayout2, 'numeric');
            app.Altavoz3X = uieditfield(app.GridLayout2, 'numeric');
            app.Altavoz4X = uieditfield(app.GridLayout2, 'numeric');
            app.Altavoz5X = uieditfield(app.GridLayout2, 'numeric');
            app.Altavoz2Y = uieditfield(app.GridLayout2, 'numeric');
            app.Altavoz3Y = uieditfield(app.GridLayout2, 'numeric');
            app.Altavoz4Y = uieditfield(app.GridLayout2, 'numeric');
            app.Altavoz5Y = uieditfield(app.GridLayout2, 'numeric');
            app.Altavoz2_grados = uieditfield(app.GridLayout2, 'numeric');
            app.Altavoz2_grados.Limits = [-360 360];
            app.Altavoz3_grados = uieditfield(app.GridLayout2, 'numeric');
            app.Altavoz3_grados.Limits = [-360 360];
            app.Altavoz4_grados = uieditfield(app.GridLayout2, 'numeric');
            app.Altavoz4_grados.Limits = [-360 360];
            app.Altavoz5_grados = uieditfield(app.GridLayout2, 'numeric');
            app.Altavoz5_grados.Limits = [-360 360];
            app.Altavoz2EditFieldLabel = uilabel(app.GridLayout2);
            app.Altavoz2EditFieldLabel.Text = 'Speaker 2';
            app.Altavoz3EditFieldLabel = uilabel(app.GridLayout2);
            app.Altavoz3EditFieldLabel.Text = 'Speaker 3';
            app.Altavoz4EditFieldLabel = uilabel(app.GridLayout2);
            app.Altavoz4EditFieldLabel.Text = 'Speaker 4';
            app.Altavoz5EditFieldLabel = uilabel(app.GridLayout2);
            app.Altavoz5EditFieldLabel.Text = 'Speaker 5';
            app.MicrophonePositionLabel = uilabel(app.GridLayout2);
            app.MicrophonePositionLabel.Text = 'Microphone Position';
            app.MicroX = uieditfield(app.GridLayout2, 'numeric');
            app.MicroY = uieditfield(app.GridLayout2, 'numeric');
            app.xLabel = uilabel(app.GridLayout2);
            app.xLabel.Text = 'X';
            app.yLabel = uilabel(app.GridLayout2);
            app.yLabel.Text = 'Y';
            app.WeatherConditionsLabel = uilabel(app.GridLayout2);
            app.WeatherConditionsLabel.Text = 'Weather Conditions';
            app.Presion = uieditfield(app.GridLayout2, 'numeric');
            app.Presion.Value = 101.325;
            app.Temp = uieditfield(app.GridLayout2, 'numeric');
            app.Temp.Value = 293.15;
            app.Humedad = uieditfield(app.GridLayout2, 'numeric');
            app.Humedad.Value = 50;
            app.PresinLabel = uilabel(app.GridLayout2);
            app.PresinLabel.Text = 'Pressure (kPa)';
            app.PresinLabel_2 = uilabel(app.GridLayout2);
            app.PresinLabel_2.Text = 'Temperature (ºK)';
            app.PresinLabel_3 = uilabel(app.GridLayout2);
            app.PresinLabel_3.Text = 'Humedad Relativa (%)';
            app.GraficaDropDown = uidropdown(app.GridLayout2);
            app.GraficaDropDown.Items = {'Heat Map', 'Heat Map (3 dB)', 'Magnitude and Phase', 'Impulse Response', 'SPL / distance (X-Axis)', 'SPL / distance (Y-Axis)', 'SPL / θ'};
            app.GraficaDropDown.ValueChangedFcn = createCallbackFcn(app, @GraficaDropDownValueChanged, true);
            app.GraficaDropDown.Value = 'Heat Map';
            app.GrficaarepresentarDropDownLabel = uilabel(app.GridLayout2);
            app.GrficaarepresentarDropDownLabel.Text = 'Graph to be plotted';
            app.Freq = uieditfield(app.GridLayout2, 'numeric');
            app.Freq.Limits = [0 Inf];
            app.Freq.Value = 1000;
            app.FrequencyHzLabel = uilabel(app.GridLayout2);
            app.FrequencyHzLabel.Text = 'Frequency (Hz)';
            app.Altavoz6X = uieditfield(app.GridLayout2, 'numeric');
            app.Altavoz6Y = uieditfield(app.GridLayout2, 'numeric');
            app.Altavoz6_grados = uieditfield(app.GridLayout2, 'numeric');
            app.Altavoz6_grados.Limits = [-360 360];
            app.Altavoz6EditFieldLabel = uilabel(app.GridLayout2);
            app.Altavoz6EditFieldLabel.Text = 'Speaker 6';
            app.tipo_alt = uilabel(app.GridLayout2);
            app.tipo_alt.Text = 'Tipo';
            app.Tipo_alt1 = uidropdown(app.GridLayout2);
            app.Tipo_alt1.Items = {'Line Array', 'Subwoofer'};
            app.Tipo_alt1.Value = 'Line Array';
            app.Tipo_alt2 = uidropdown(app.GridLayout2);
            app.Tipo_alt2.Items = {'Line Array', 'Subwoofer'};
            app.Tipo_alt2.Value = 'Line Array';
            app.Tipo_alt3 = uidropdown(app.GridLayout2);
            app.Tipo_alt3.Items = {'Line Array', 'Subwoofer'};
            app.Tipo_alt3.Value = 'Line Array';
            app.Tipo_alt4 = uidropdown(app.GridLayout2);
            app.Tipo_alt4.Items = {'Line Array', 'Subwoofer'};
            app.Tipo_alt4.Value = 'Line Array';
            app.Tipo_alt5 = uidropdown(app.GridLayout2);
            app.Tipo_alt5.Items = {'Line Array', 'Subwoofer'};
            app.Tipo_alt5.Value = 'Line Array';
            app.Tipo_alt6 = uidropdown(app.GridLayout2);
            app.Tipo_alt6.Items = {'Line Array', 'Subwoofer'};
            app.Tipo_alt6.Value = 'Line Array';
            app.dBrangeSliderLabel = uilabel(app.GridLayout2);
            app.dBrangeSliderLabel.Text = 'dB range';
            app.dBrangeSlider = uislider(app.GridLayout2);
            app.dBrangeSlider.Limits = [20 80];
            app.dBrangeSlider.ValueChangedFcn = createCallbackFcn(app, @dBrangeSliderValueChanged, true);
            app.dBrangeSlider.Value = 40;
            app.PanelGraf = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.PanelGraf);
            app.EjeXEditFieldLabel = uilabel(app.GridLayout3);
            app.EjeXEditFieldLabel.Text = 'X-axis';
            app.EjeX = uieditfield(app.GridLayout3, 'numeric');
            app.EjeX.Limits = [0 Inf];
            app.EjeX.Value = 20;
            app.XoffsetLabel = uilabel(app.GridLayout3);
            app.XoffsetLabel.Text = 'X offset';
            app.OffsetX = uieditfield(app.GridLayout3, 'numeric');
            app.OffsetX.RoundFractionalValues = 'on';
            app.OffsetX.Value = -10;
            app.EjeYEditField_2Label = uilabel(app.GridLayout3);
            app.EjeYEditField_2Label.Text = 'Y-axis';
            app.EjeY = uieditfield(app.GridLayout3, 'numeric');
            app.EjeY.Limits = [0 Inf];
            app.EjeY.RoundFractionalValues = 'on';
            app.EjeY.Value = 20;
            app.OffsetYEditField_2Label = uilabel(app.GridLayout3);
            app.OffsetYEditField_2Label.Text = 'Y offset';
            app.OffsetY = uieditfield(app.GridLayout3, 'numeric');
            app.OffsetY.RoundFractionalValues = 'on';
            app.OffsetY.Value = -10;
            app.ResolutionXLabel = uilabel(app.GridLayout3);
            app.ResolutionXLabel.Text = 'Resolution X';
            app.ResolucionX = uieditfield(app.GridLayout3, 'numeric');
            app.ResolucionX.Limits = [0 Inf];
            app.ResolucionX.Value = 0.1;
            app.ResolucinXLabel_2 = uilabel(app.GridLayout3);
            app.ResolucinXLabel_2.Text = 'Resolution Y';
            app.ResolucionY = uieditfield(app.GridLayout3, 'numeric');
            app.ResolucionY.Limits = [0 Inf];
            app.ResolucionY.Value = 0.1;
            app.PanelM_F = uipanel(app.GridLayout);
            app.imagen_logo = uiimage(app.PanelM_F);
            app.imagen_logo.ImageSource = fullfile(pathToMLAPP, 'EscudoIT', 'portada_app.png');
            app.PanelProm = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.PanelProm);
            app.AveragedDropDownLabel = uilabel(app.GridLayout4);
            app.AveragedDropDownLabel.Text = 'Averaged';
            app.AveragedDropDown = uidropdown(app.GridLayout4);
            app.AveragedDropDown.Items = {'1', '1/3', '1/6', '1/12', '1/24'};
            app.AveragedDropDown.ValueChangedFcn = createCallbackFcn(app, @AveragedDropDownValueChanged, true);
            app.AveragedDropDown.Value = '1/12';
            app.PanelAcciones = uipanel(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.PanelAcciones);
            app.LineArraySpeakersButton = uibutton(app.GridLayout5, 'push');
            app.LineArraySpeakersButton.ButtonPushedFcn = createCallbackFcn(app, @LineArraySpeakersButtonPushed, true);
            app.LineArraySpeakersButton.Text = 'Line Array Speakers';
            app.SimulateRepresentButton = uibutton(app.GridLayout5, 'push');
            app.SimulateRepresentButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateRepresentButtonPushed, true);
            app.SimulateRepresentButton.Text = 'Simulate & Represent';
            app.SubwooferSpeakersButton = uibutton(app.GridLayout5, 'push');
            app.SubwooferSpeakersButton.ButtonPushedFcn = createCallbackFcn(app, @SubwooferSpeakersButtonPushed, true);
            app.SubwooferSpeakersButton.Text = 'Subwoofer Speakers';
            % Show the figure after all components are created
            app.PredicciondelcomportamientoacsticodeAltavocesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_eng

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PredicciondelcomportamientoacsticodeAltavocesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PredicciondelcomportamientoacsticodeAltavocesUIFigure)
        end
    end
end